classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ParamethersLabel               matlab.ui.control.Label
        InputsLabel                    matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        ChoosewhichparametheryouwanttocalculateLabel  matlab.ui.control.Label
        SamplingRateHzEditFieldLabel   matlab.ui.control.Label
        SamplingRateHzEditField        matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel      matlab.ui.control.Label
        FrequencyHzEditField           matlab.ui.control.NumericEditField
        SignalDurationsEditFieldLabel  matlab.ui.control.Label
        SignalDurationsEditField       matlab.ui.control.NumericEditField
        ALERTALabel                    matlab.ui.control.Label
        AEditField                     matlab.ui.control.NumericEditField
        BEditField                     matlab.ui.control.NumericEditField
        RUNButton                      matlab.ui.control.Button
        Alabel                         matlab.ui.control.Label
        Blabel                         matlab.ui.control.Label
        Clabel                         matlab.ui.control.Label
        CEditFile                      matlab.ui.control.NumericEditField
        RightPanel                     matlab.ui.container.Panel
        ResultsLabel                   matlab.ui.control.Label
        fft_fr                         matlab.ui.control.UIAxes
        In_time                        matlab.ui.control.UIAxes
        Out_time                       matlab.ui.control.UIAxes
        THDEditFieldLabel              matlab.ui.control.Label
        THDEditField                   matlab.ui.control.EditField
        DFEditFieldLabel               matlab.ui.control.Label
        DFEditField                    matlab.ui.control.EditField
        GainEditFieldLabel             matlab.ui.control.Label
        GainEditField                  matlab.ui.control.EditField
        THDNEditFieldLabel             matlab.ui.control.Label
        THDNEditField                  matlab.ui.control.EditField
        IMDEditFieldLabel              matlab.ui.control.Label
        IMDEditField                   matlab.ui.control.EditField
        PwrEditFieldLabel              matlab.ui.control.Label
        PwrEditField                   matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AEditField.Visible='off';
            app.Alabel.Visible='off';
            app.BEditField.Visible='off';
            app.Blabel.Visible='off';
            app.CEditFile.Visible = 'off';
            app.Clabel.Visible = 'off';
            app.ALERTALabel.Visible='off';
            app.THDEditField.Editable = 'off';
            app.THDNEditField.Editable = 'off';
            app.DFEditField.Editable = 'off';
            app.IMDEditField.Editable = 'off';
            app.GainEditField.Editable = 'off';
            app.PwrEditField.Editable = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {441, 441};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {208, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            value = app.DropDown.Value;
            app.ALERTALabel.Text=value;
            switch value
                case 'THD'                    
                    if (app.SamplingRateHzEditField.Value == 0 || app.FrequencyHzEditField.Value == 0 || app.SignalDurationsEditField.Value == 0)
                        app.ALERTALabel.Text='FILL ALL INPUTS';
                        app.ALERTALabel.Visible='on';
                        return
                    end
                    app.ALERTALabel.Visible='off';
                    
                    Fs = app.SamplingRateHzEditField.Value;
                    duration = app.SignalDurationsEditField.Value;
                    
                    F = app.FrequencyHzEditField.Value;
                    Fd = F*duration;
                    t = 0:(1/Fs):duration-(1/Fs);
                    n = 0:1:duration*Fs-(1/Fs);
                    x = sin(2*pi*n*(Fd/Fs))';
                    
                    player   = audioplayer(x, Fs, 16);           
                    recorder = audiorecorder(Fs, 16, 2);
                    
                    record(recorder,duration)              
                    playblocking(player); 
                    
                    stop(recorder)   
                    signal = getaudiodata(recorder, 'double');
                    [N,M] = size(signal);
                    
                    f = abs(fft(signal));
                    
                    x_max = 1;
        
                    % Busquem a quina posició (x) es troba el màxim
                    for i = 1:1:N/2
                        if (f(i) > f(x_max))
                            x_max = i;
                        end
                    end
                    
                    harmonics = x_max;                  % Vector amb les posicions dels harmònics
                    delta_x = round(x_max*0.2);
                    num_harm = fix((N/2)/x_max);        % Càlcul del número d'harmònics generats
                    
                    % Cerca de la posició de cada harmònic
                    for i = 2:1:num_harm
                        hs = i*x_max;
                        for j = (i*x_max-delta_x):1:(i*x_max+delta_x)
                            if (f(j) > f(hs))
                                hs = j;
                            end
                        end
                        % Només agafem els harmònics amb una potència superior al 0.01%
                        % respecte al principal
                        if (f(hs)/f(x_max) > 0.01)
                            harmonics(end+1) = hs;
                        end
                    end
                    
                    num = 0;
                    [m,n] = size(harmonics);
                    
                    % Càlcul de la potència dels harmònics secundàris
                    for i = 2:1:n
                        num = num + (f(harmonics(i)))^2;
                    end
                    
                    thd = (sqrt(num)/f(harmonics(1)))*100;
                    app.THDEditField.Value = sprintf('%.2f', thd);
                    
                    plot(app.In_time, t, x);
                    app.In_time.Title.String = 'Input Signal (Time domain)';
                    
                    plot(app.Out_time, t, signal);
                    app.Out_time.Title.String = 'Output Signal (Time domain)'; 
                    
                    app.fft_fr.XLim = [0 (harmonics(end)+harmonics(1))];
                    plot(app.fft_fr, abs(fft(signal)));
                    app.fft_fr.Title.String = 'FFT (frequency domain)';
        
                case 'THD + N'
                    if (app.SamplingRateHzEditField.Value == 0 || app.FrequencyHzEditField.Value == 0 || app.SignalDurationsEditField.Value == 0)
                        app.ALERTALabel.Text='FILL ALL INPUTS';
                        app.ALERTALabel.Visible='on';
                        return
                    end
                    app.ALERTALabel.Visible='off';
                    
                    Fs = app.SamplingRateHzEditField.Value;
                    duration = app.SignalDurationsEditField.Value;
                    
                    F = app.FrequencyHzEditField.Value;
                    Fd = F*duration;
                    t = 0:(1/Fs):duration-(1/Fs);
                    n = 0:1:duration*Fs-(1/Fs);
                    x = sin(2*pi*n*(Fd/Fs))';
                    
                    player   = audioplayer(x, Fs, 16);           
                    recorder = audiorecorder(Fs, 16, 2);
                    
                    record(recorder,duration)              
                    playblocking(player); 
                    
                    stop(recorder)   
                    signal = getaudiodata(recorder, 'double');
                    [N,M] = size(signal);
                    
                    f = abs(fft(signal));
                    
                    x_max = 1;
        
                    % Busquem a quina posició (x) es troba el màxim
                    for i = 1:1:N/2
                        if (f(i) > f(x_max))
                            x_max = i;
                        end
                    end
                    
                    % Càlcul de la potència de tot el senyal menys de l'harmonic
                    % principal
                    
                    f_harm_prin = f(:,1);
                    f_seny_sense_harm = f(:,1);
                    
                    rang = round(0.1*x_max);
                    
                    for i = 1:1:(x_max - rang)
                        f_harm_prin(i) = 0;
                    end
                    for i = (x_max - rang):1:(x_max + rang)
                        f_seny_sense_harm(i) = 0;
                    end
                    for i = (x_max + rang):1:(Fs - x_max - rang)
                        f_harm_prin(i) = 0;
                    end
                    for i = (Fs - x_max - rang):1:(Fs - x_max + rang)
                        f_seny_sense_harm(i) = 0;
                    end
                    for i = (Fs - x_max + rang):1:Fs-1
                        f_harm_prin(i) = 0;
                    end
                    
                    a = ifft(f_seny_sense_harm);
                    b = ifft(f_harm_prin);
                    
                    P = sum((abs(a)).^2)/Fs;
                    Pa = sum((abs(b)).^2)/Fs;
                    
                    harmonics = x_max;                  % Vector amb les posicions dels harmònics
                    delta_x = round(x_max*0.2);
                    num_harm = fix((N/2)/x_max);        % Càlcul del número d'harmònics generats
                    
                    % Cerca de la posició de cada harmònic
                    for i = 2:1:num_harm
                        hs = i*x_max;
                        for j = (i*x_max-delta_x):1:(i*x_max+delta_x)
                            if (f(j) > f(hs))
                                hs = j;
                            end
                        end
                        % Només agafem els harmònics amb una potència superior al 0.01%
                        % respecte al principal
                        if (f(hs)/f(x_max) > 0.01)
                            harmonics(end+1) = hs;
                        end
                    end
                    
                    % Càlcul del THD + N
                    thdn = (P/Pa)*100;
                    app.THDNEditField.Value = sprintf('%.2f', thdn);
                    
                    plot(app.In_time, t, x);
                    app.In_time.Title.String = 'Input Signal (Time domain)';
                    
                    plot(app.Out_time, t, signal);
                    app.Out_time.Title.String = 'Output Signal (Time domain)'; 
                    
                    app.fft_fr.XLim = [0 (harmonics(end)+harmonics(1))];
                    plot(app.fft_fr, abs(fft(signal)));
                    app.fft_fr.Title.String = 'FFT (frequency domain)';
                
                case 'DF'
                    if (app.SamplingRateHzEditField.Value == 0 || app.FrequencyHzEditField.Value == 0 || app.SignalDurationsEditField.Value == 0)
                        app.ALERTALabel.Text='FILL ALL INPUTS';
                        app.ALERTALabel.Visible='on';
                        return
                    end
                    app.ALERTALabel.Visible='off';
                    
                    Fs = app.SamplingRateHzEditField.Value;
                    duration = app.SignalDurationsEditField.Value;
                    
                    F = app.FrequencyHzEditField.Value;
                    Fd = F*duration;
                    t = 0:(1/Fs):duration-(1/Fs);
                    n = 0:1:duration*Fs-(1/Fs);
                    x = sin(2*pi*n*(Fd/Fs))';
                    
                    player   = audioplayer(x, Fs, 16);           
                    recorder = audiorecorder(Fs, 16, 2);
                    
                    record(recorder,duration)              
                    playblocking(player); 
                    
                    stop(recorder)   
                    signal = getaudiodata(recorder, 'double');
                    [N,M] = size(signal);
                    
                    f = abs(fft(signal));
                    
                    x_max = 1;
        
                    % Busquem a quina posició (x) es troba el màxim
                    for i = 1:1:N/2
                        if (f(i) > f(x_max))
                            x_max = i;
                        end
                    end
                    
                    harmonics = x_max;                  % Vector amb les posicions dels harmònics
                    delta_x = round(x_max*0.2);
                    num_harm = fix((N/2)/x_max);        % Càlcul del número d'harmònics generats
                    
                    % Cerca de la posició de cada harmònic
                    for i = 2:1:num_harm
                        hs = i*x_max;
                        for j = (i*x_max-delta_x):1:(i*x_max+delta_x)
                            if (f(j) > f(hs))
                                hs = j;
                            end
                        end
                        % Només agafem els harmònics amb una potència superior al 0.01%
                        % respecte al principal
                        if (f(hs)/f(x_max) > 0.01)
                            harmonics(end+1) = hs;
                        end
                    end
                    
                    num = 0;
                    [m,n] = size(harmonics);
                    
                    % Càlcul de la potència dels harmònics secundàris
                    for i = 1:1:n
                        num = num + (f(harmonics(i)))^2;
                    end
                    
                    df = (f(harmonics(1))/sqrt(num))*100;
                    app.DFEditField.Value = sprintf('%.2f', df);
                    
                    plot(app.In_time, t, x);
                    app.In_time.Title.String = 'Input Signal (Time domain)';
                    
                    plot(app.Out_time, t, signal);
                    app.Out_time.Title.String = 'Output Signal (Time domain)'; 
                    
                    app.fft_fr.XLim = [0 (harmonics(end)+harmonics(1))];
                    plot(app.fft_fr, abs(fft(signal)));
                    app.fft_fr.Title.String = 'FFT (frequency domain)';
                    
                case 'IMD'
                    if (app.SamplingRateHzEditField.Value == 0 || app.FrequencyHzEditField.Value == 0 || app.SignalDurationsEditField.Value == 0 || app.AEditField == 0 || app.BEditField == 0)
                        app.ALERTALabel.Text='FILL ALL INPUTS';
                        app.ALERTALabel.Visible='on';
                        return
                    end
                    app.ALERTALabel.Visible='off';
                    
                    Fs = app.SamplingRateHzEditField.Value;
                    duration = app.SignalDurationsEditField.Value;
                    
                    F1 = app.FrequencyHzEditField.Value;
                    F2 = app.AEditField.Value;
                    Fd1 = F1*duration;
                    Fd2 = F2*duration;
                    t = 0:(1/Fs):duration-(1/Fs);
                    n = 0:1:duration*Fs-(1/Fs);
                    x1 = sin(2*pi*n*(Fd1/Fs))';
                    x2 = sin(2*pi*n*(Fd2/Fs))';
                    f01 = abs(fft(x1)); 
                    f02 = abs(fft(x2));         
                    x = x1 + x2;                     

                    
                    player   = audioplayer(x, Fs, 16);           
                    recorder = audiorecorder(Fs, 16, 2);
                    
                    record(recorder,duration)              
                    playblocking(player); 
                    
                    stop(recorder)   
                    signal = getaudiodata(recorder, 'double');
                    [N,M] = size(signal);
                    
                    f = abs(fft(signal));
                    
                    s = f;
                    
                    x_max_1 = 1;
                    x_max_2 = 2;
        
                    % Busquem a quina posició (x) es troba el màxim
                    for i = 1:1:N/2
                        if (s(i) > s(x_max_1))
                            x_max_1 = i;
                        end
                    end
                    
                    rang = round(0.1*x_max_1);
                    
                    % Valor del primer màxim a zero per no generar problemes en la cerca del segon
                    for i = (x_max_1 - rang):1:(x_max_1 + rang)
                        s(i) = 0;
                    end
                    
                    % Càlcul del segon màxim
                    for i = 1:1:N/2
                        if (s(i) > s(x_max_2))
                            x_max_2 = i;
                        end
                    end
                    
                    % Càlcul de la posició dels harmònics secundàris
                    harmonics = 1;
                    
                    if (x_max_1 > x_max_2)
                        max_absolut = x_max_1;
                    else
                        max_absolut = x_max_2;
                    end
                    
                    delta_x = round(((x_max_1+x_max_2)/2)*0.03);
                    harmonics(end+1) = x_max_1;
                    harmonics(end+1) = x_max_2;
                    ordre = app.BEditField.Value;
                    
                    % Cerca de la posició dels harmònics secundaris
                    for i = -ordre:1:ordre
                        for j = -ordre:1:ordre
                            if (abs(i) + abs(j) == ordre)
                                pos = i*x_max_1 + j*x_max_2;
                                if (pos > 0 && pos < (N/2))
                                    for k = (pos - delta_x):1:(pos + delta_x)
                                        if (f(k) > f(pos))
                                            pos = k;
                                        end
                                    end  
                                    if (f(pos)/f(max_absolut) > 0.01)
                                        harmonics(end+1) = pos;
                                    end
                                end
                            end
                        end
                    end
                    
                    %% IMD
                    
                    num = 0;
                    den = 0;
                    [m,n] = size(harmonics);
                    
                    % Càlcul de la potència dels harmònics secundàris
                    for i = 3:1:n
                        num = num + (f(harmonics(i)))^2;
                    end
                    
                    % Càlcul de la potència dels harmònics principals
                    for i = 1:1:2
                        den = den + (f(harmonics(i)))^2;
                    end
                    
                    imd = (sqrt(num)/sqrt(den))*100;
                    app.IMDEditField.Value = sprintf('%.2f', imd);
                    
                    plot(app.In_time, t, x);
                    app.In_time.Title.String = 'Input Signal (Time domain)';
                    
                    plot(app.Out_time, t, signal);
                    app.Out_time.Title.String = 'Output Signal (Time domain)'; 
                    
                    app.fft_fr.XLim = [0 (harmonics(end)+harmonics(1))];
                    plot(app.fft_fr, abs(fft(signal)));
                    app.fft_fr.Title.String = 'FFT (frequency domain)';
                
                case 'Gain'
                    if (app.SamplingRateHzEditField.Value == 0 || app.FrequencyHzEditField.Value == 0 || app.SignalDurationsEditField.Value == 0)
                        app.ALERTALabel.Text='FILL ALL INPUTS';
                        app.ALERTALabel.Visible='on';
                        return
                    end
                    app.ALERTALabel.Visible='off';
                    
                    Fs = app.SamplingRateHzEditField.Value;
                    duration = app.SignalDurationsEditField.Value;
                    
                    F = app.FrequencyHzEditField.Value;
                    Fd = F*duration;
                    t = 0:(1/Fs):duration-(1/Fs);
                    n = 0:1:duration*Fs-(1/Fs);
                    x = sin(2*pi*n*(Fd/Fs))';
                    f0 = abs(fft(x));        
                    
                    player   = audioplayer(x, Fs, 16);           
                    recorder = audiorecorder(Fs, 16, 2);
                    
                    record(recorder,duration)              
                    playblocking(player); 
                    
                    stop(recorder)   
                    signal = getaudiodata(recorder, 'double');
                    [N,M] = size(signal);
                    
                    %% Calculem la fft de la senyal de sortida
                    f = abs(fft(signal(:,1)));
                    
                    %% Busquem a quina posició (x) es troba el màxim
                    x_max = 1;
                    
                    for i = 1:1:N/2
                        if (f(i) > f(x_max))
                            x_max = i;
                        end
                    end
                    
                    harmonics = x_max;                 
                    delta_x = round(x_max*0.2);
                    num_harm = round((N/2)/x_max);
                    
                    % Cerca de la posició de cada harmònic
                    for i = 2:1:num_harm
                        hs = i*x_max;
                        for j = (i*x_max-delta_x):1:(i*x_max+delta_x)
                            if (f(j) > f(hs))
                                hs = j;
                            end
                        end
                        % Només agafem els harmònics amb una potència superior al 0.01%
                        % respecte al principal
                        if (f(hs)/f(x_max) > 0.01)
                            harmonics(end+1) = hs;
                        end
                    end
                    
                    %% Calculem el guany
                    gain = f(harmonics(1))/f0(F+1);
                    app.GainEditField.Value = sprintf('%.2f', gain);
                    
                    plot(app.In_time, t, x);
                    app.In_time.Title.String = 'Input Signal (Time domain)';
                    
                    plot(app.Out_time, t, signal);
                    app.Out_time.Title.String = 'Output Signal (Time domain)'; 
                    
                    app.fft_fr.XLim = [0 (harmonics(end)+harmonics(1))];
                    plot(app.fft_fr, abs(fft(signal)));
                    app.fft_fr.Title.String = 'FFT (frequency domain)';
                    
                case 'Power'
                    if (app.SamplingRateHzEditField.Value == 0 || app.FrequencyHzEditField.Value == 0 || app.SignalDurationsEditField.Value == 0)
                        app.ALERTALabel.Text='FILL ALL INPUTS';
                        app.ALERTALabel.Visible='on';
                        return
                    end
                    app.ALERTALabel.Visible='off';
                    
                    Fs = app.SamplingRateHzEditField.Value;
                    duration = app.SignalDurationsEditField.Value;
                    
                    F = app.FrequencyHzEditField.Value;
                    Fd = F*duration;
                    t = 0:(1/Fs):duration-(1/Fs);
                    n = 0:1:duration*Fs-(1/Fs);
                    x = sin(2*pi*n*(Fd/Fs))';
                    
                    player   = audioplayer(x, Fs, 16);           
                    recorder = audiorecorder(Fs, 16, 2);
                    
                    record(recorder,duration)              
                    playblocking(player); 
                    
                    stop(recorder)   
                    signal = getaudiodata(recorder, 'double');
                    [N,M] = size(signal);
                    
                    %% Calculem la fft de la senyal de sortida
                    f = abs(fft(signal(:,1)));
                    
                    %% Busquem a quina posició (x) es troba el màxim
                    x_max = 1;
                    
                    for i = 1:1:N/2
                        if (f(i) > f(x_max))
                            x_max = i;
                        end
                    end
                    
                    harmonics = x_max;                 
                    delta_x = round(x_max*0.2);
                    num_harm = round((N/2)/x_max);
                    
                    % Cerca de la posició de cada harmònic
                    for i = 2:1:num_harm
                        hs = i*x_max;
                        for j = (i*x_max-delta_x):1:(i*x_max+delta_x)
                            if (f(j) > f(hs))
                                hs = j;
                            end
                        end
                        % Només agafem els harmònics amb una potència superior al 0.01%
                        % respecte al principal
                        if (f(hs)/f(x_max) > 0.01)
                            harmonics(end+1) = hs;
                        end
                    end

                    %% Calculem la potencia

                    s = 0;
                    Pot=0;
                    
                    % Busquem l'amplitud mitja de les primeres mostres per establir un llindar a partir del
                    % cual considerarem que comença la grabació
                    for i = 1:1:1000                             
                        s = s + abs(signal(i,1));
                    end
                    
                    s = s/1000;
                                        
                    % Càlcul de la potència mitja del senyal
                    for j = 1000:N
                        if(abs(signal(j,1))>2*s)
                            Pot = Pot + abs(signal(j,1)).^2;
                        end
                    end
                    
                    %Calculem la potència
                    pwr = Pot/(N-1000);
                    app.PwrEditField.Value = sprintf('%f', pwr);
                                        
                    plot(app.In_time, t, x);
                    app.In_time.Title.String = 'Input Signal (Time domain)';
                    
                    plot(app.Out_time, t, signal);
                    app.Out_time.Title.String = 'Output Signal (Time domain)'; 
                    
                    app.fft_fr.XLim = [0 (harmonics(end)+harmonics(1))];
                    plot(app.fft_fr, abs(fft(signal)));
                    app.fft_fr.Title.String = 'FFT (frequency domain)';
                        
                case 'FR'
                    if (app.SamplingRateHzEditField.Value == 0 || app.SignalDurationsEditField.Value == 0 || app.AEditField.Value == 0 || app.BEditField.Value == 0 || app.CEditFile.Value == 0)
                        app.ALERTALabel.Text='FILL ALL INPUTS';
                        app.ALERTALabel.Visible='on';
                        return
                    end
                    app.ALERTALabel.Visible='off';
                    
                    Fs = app.SamplingRateHzEditField.Value;
                    duration = app.SignalDurationsEditField.Value;

                    n = 0:1:duration*Fs-(1/Fs);
                    
                    fMin = log10(app.AEditField.Value);
                    fMax = log10(app.BEditField.Value);
                    nSinusoids = app.CEditFile.Value;
                    
                    freq = logspace(fMin, fMax, nSinusoids);
                    gain = linspace(0, 0, nSinusoids);
                    
                    %% Reproducció i grabació de la sinusoide a diferents freqüències

                    for j=1:nSinusoids
                        x = 2*sin(2*pi*n*((freq(j)*duration)/Fs))'; 
                        f0 = abs(fft(x));  
                        
                        x_max_in = 1;
                    
                        for r = 1:1:Fs/2
                            if (f0(r) > f0(x_max_in))
                                x_max_in = r;
                            end
                        end
                        
                        harmonics_in = x_max_in;
                        delta_x_in = round(x_max_in*0.2);
                        num_harm_in = fix((Fs/2)/x_max_in);
                        
                        for r = 2:1:num_harm_in
                            hs = r*x_max_in;
                            for p = (r*x_max_in-delta_x_in):1:(r*x_max_in+delta_x_in)
                                if (f0(p) > f0(hs))
                                    hs = p;
                                end
                            end
                            if (f0(hs)/f0(x_max_in) > 0.01)
                                harmonics_in(end+1) = hs;
                            end
                        end
                        
                        player = audioplayer(x, Fs, 16);           
                        recorder = audiorecorder(Fs, 16, 2);
                    
                        record(recorder,duration)
                        playblocking(player);  
                    
                        stop(recorder)                       
                        signal = getaudiodata(recorder, 'double');
                        [N,M]=size(signal);
                     
                        f=abs(fft(signal(:,1)));
                        
                        x_max_out = 1;
                        
                        for i = 1:1:N/2
                            if (f(i) > f(x_max_out))
                                x_max_out = i;
                            end
                        end
                        
                        harmonics_out = x_max_out;
                        delta_x_out = round(x_max_out*0.2);
                        num_harm_out = round((N/2)/x_max_out);
                        
                        for i = 2:1:num_harm_out
                            hs = i*x_max_out;
                            for k = (i*x_max_out-delta_x_out):1:(i*x_max_out+delta_x_out)
                                if (f(k) > f(hs))
                                    hs = k;
                                end
                            end
                            if (f(hs)/f(x_max_out) > 0.01)
                                harmonics_out(end+1) = hs;
                            end
                        end
                            
                        % Càlcul del guany en dB
                        
                        gain(j) = 10*log10(f(harmonics_out(1))/f0(harmonics_in(1)));  
                    end
                    
                    plot(app.In_time, 0)
                    plot(app.Out_time, 0)
                    
                    app.fft_fr.XLim = [app.AEditField.Value app.BEditField.Value];
                    app.fft_fr.YLim = [min(gain) max(gain)];
                    app.fft_fr.XGrid = 'on';
                    app.fft_fr.YGrid = 'on';
                    semilogx(app.fft_fr, freq, gain);
                    app.fft_fr.Title.String = 'FFT (frequency domain)';                    
                    
                otherwise
                    app.ALERTALabel.Text='CHOOSE ONE PARAMETHER';
                    app.ALERTALabel.Visible='on';
                    
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if strcmp(value, 'THD')
                app.Alabel.Visible = 'off';
                app.AEditField.Visible = 'off';
                app.Blabel.Visible = 'off';
                app.BEditField.Visible = 'off';
                app.Clabel.Visible = 'off';
                app.CEditFile.Visible = 'off';
            end
            if strcmp(value, 'THD + N')
                app.Alabel.Visible = 'off';
                app.AEditField.Visible = 'off';
                app.Blabel.Visible = 'off';
                app.BEditField.Visible = 'off';
                app.Clabel.Visible = 'off';
                app.CEditFile.Visible = 'off';
            end
            if strcmp(value, 'DF')
                app.Alabel.Visible = 'off';
                app.AEditField.Visible = 'off';
                app.Blabel.Visible = 'off';
                app.BEditField.Visible = 'off';
                app.Clabel.Visible = 'off';
                app.CEditFile.Visible = 'off';
            end
            if strcmp(value, 'IMD')
                app.Alabel.Text = 'Frequency 2';
                app.Alabel.Visible = 'on';
                app.AEditField.Visible = 'on';
                app.Blabel.Text = 'IMD Order';
                app.Blabel.Visible = 'on';
                app.BEditField.Visible = 'on';
                app.Clabel.Visible = 'off';
                app.CEditFile.Visible = 'off';
            end
            if strcmp(value, 'Gain')
                app.Alabel.Visible = 'off';
                app.AEditField.Visible = 'off';
                app.Blabel.Visible = 'off';
                app.BEditField.Visible = 'off';
                app.Clabel.Visible = 'off';
                app.CEditFile.Visible = 'off';
            end
            if strcmp(value, 'Power')
                app.Alabel.Visible = 'off';
                app.AEditField.Visible = 'off';
                app.Blabel.Visible = 'off';
                app.BEditField.Visible = 'off';
                app.Clabel.Visible = 'off';
                app.CEditFile.Visible = 'off';
            end
            if strcmp(value, 'FR')
                app.Alabel.Text = 'Lower Frequency';
                app.Alabel.Visible = 'on';
                app.AEditField.Visible = 'on';
                app.Blabel.Text = 'Higher Frequency';
                app.Blabel.Visible = 'on';
                app.BEditField.Visible = 'on';
                app.Clabel.Text = 'Sinusoids Number';
                app.Clabel.Visible = 'on';
                app.CEditFile.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParamethersLabel = uilabel(app.LeftPanel);
            app.ParamethersLabel.Text = 'Paramethers';
            app.InputsLabel = uilabel(app.LeftPanel);
            app.InputsLabel.Text = 'Inputs';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'', 'THD', 'THD + N', 'DF', 'IMD', 'Gain', 'Power', 'FR'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '';
            app.ChoosewhichparametheryouwanttocalculateLabel = uilabel(app.LeftPanel);
            app.ChoosewhichparametheryouwanttocalculateLabel.Text = {'Choose which paramether '; 'you want to calculate'};
            app.SamplingRateHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate [Hz]';
            app.SamplingRateHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency [Hz]';
            app.FrequencyHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SignalDurationsEditFieldLabel = uilabel(app.LeftPanel);
            app.SignalDurationsEditFieldLabel.Text = 'Signal Duration [s]';
            app.SignalDurationsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ALERTALabel = uilabel(app.LeftPanel);
            app.ALERTALabel.Text = 'ALERTA';
            app.AEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RUNButton = uibutton(app.LeftPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Alabel = uilabel(app.LeftPanel);
            app.Blabel = uilabel(app.LeftPanel);
            app.Clabel = uilabel(app.LeftPanel);
            app.CEditFile = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.ResultsLabel = uilabel(app.RightPanel);
            app.ResultsLabel.Text = 'Results';
            app.fft_fr = uiaxes(app.RightPanel);
            title(app.fft_fr, 'FFT or FR')
            xlabel(app.fft_fr, 'Frequency [Hz]')
            ylabel(app.fft_fr, 'Amplitude')
            app.In_time = uiaxes(app.RightPanel);
            title(app.In_time, 'Input Signal (Time domain)')
            xlabel(app.In_time, 'Time [s]')
            ylabel(app.In_time, 'Amplitude')
            app.Out_time = uiaxes(app.RightPanel);
            title(app.Out_time, 'Output Signal (Time domain)')
            xlabel(app.Out_time, 'Time [s]')
            ylabel(app.Out_time, 'Amplitude')
            app.THDEditFieldLabel = uilabel(app.RightPanel);
            app.THDEditFieldLabel.Text = 'THD';
            app.THDEditField = uieditfield(app.RightPanel, 'text');
            app.DFEditFieldLabel = uilabel(app.RightPanel);
            app.DFEditFieldLabel.Text = 'DF';
            app.DFEditField = uieditfield(app.RightPanel, 'text');
            app.GainEditFieldLabel = uilabel(app.RightPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.RightPanel, 'text');
            app.THDNEditFieldLabel = uilabel(app.RightPanel);
            app.THDNEditFieldLabel.Text = 'THD+N';
            app.THDNEditField = uieditfield(app.RightPanel, 'text');
            app.IMDEditFieldLabel = uilabel(app.RightPanel);
            app.IMDEditFieldLabel.Text = 'IMD';
            app.IMDEditField = uieditfield(app.RightPanel, 'text');
            app.PwrEditFieldLabel = uilabel(app.RightPanel);
            app.PwrEditFieldLabel.Text = 'Pwr';
            app.PwrEditField = uieditfield(app.RightPanel, 'text');
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'logo2.png';
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.ImageSource = 'logo.PNG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end